classdef loopShapingGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LoopShapingGUI                  matlab.ui.Figure
        ControllerPanel                 matlab.ui.container.Panel
        LeadLag                         matlab.ui.control.Button
        Notch                           matlab.ui.control.Button
        Gain                            matlab.ui.control.Button
        PID                             matlab.ui.control.Button
        AddPolesZeros                   matlab.ui.control.Button
        AutomaticDesigner               matlab.ui.control.Button
        ResetContoller                  matlab.ui.control.Button
        ControllerText                  matlab.ui.control.Label
        CLBode                          matlab.ui.control.Button
        Nichols                         matlab.ui.control.Button
        CurrentController               matlab.ui.control.Label
        OpenLoopBodeButton              matlab.ui.control.Button
        EditControllerEditFieldLabel    matlab.ui.control.Label
        EditCont_num                    matlab.ui.control.EditField
        EditButton                      matlab.ui.control.Button
        DeleteControllerEditFieldLabel  matlab.ui.control.Label
        DelCont_num                     matlab.ui.control.EditField
        DeleteButton                    matlab.ui.control.Button
        ControllersList                 matlab.ui.control.ListBox
        NicholsBodePanel                matlab.ui.container.Panel
        nicholsPlot                     matlab.ui.control.UIAxes
        ControllerPolesandZerosPanel    matlab.ui.container.Panel
        zpkMap                          matlab.ui.control.UIAxes
        zpkTable_p                      matlab.ui.control.Table
        zpkTable_z                      matlab.ui.control.Table
        OptionsPanel                    matlab.ui.container.Panel
        SaveController                  matlab.ui.control.Button
        LoadController                  matlab.ui.control.Button
        LoadPlant                       matlab.ui.control.Button
        Help                            matlab.ui.control.Button
        Discrete                        matlab.ui.control.Button
        savenExit                       matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        LoopShapingGUILabel             matlab.ui.control.Label
        QFTLogo                         matlab.ui.control.Image
    end

    
    properties (Access = private)
        DialogApp_gain;
    end
    
    properties (Access = public)
        G = evalin('base','G'); % Description
        Controller;
        des = evalin("base",'des');
        gain_flag;
        leadLag_flag;
        pid_flag;
        notch_flag;
        pz_flag;
    end
    
    methods (Access = private)
        function [] = printGtext(app,G)
            Gtf = tf(G);
            syms s
            [num_P,den_P] = tfdata(Gtf);
            Gsym = vpa(poly2sym((num_P),s)/poly2sym((den_P),s),2);
            Gstr = print_sym_fraction(Gsym);
            app.CurrentController.Text = Gstr;
        end
    end
    
    methods (Access = public)
        function [] = printCLbode(app,G,des)
            app.nicholsPlot.cla
            wnom = des.nom.frequency;
            Cwnom = squeeze(freqresp(G,wnom)); % in complex plain
            Fwnom = squeeze(freqresp(tf(1),wnom)); % in complex plain
            Pnom = n2c(des.nom.response);
            Lnom = Cwnom.*Pnom;
            Tnom = Fwnom.*Lnom./(1+Lnom);
            T_mag = mag2db(abs(Tnom));
            semilogx(app.nicholsPlot,wnom,T_mag)
            app.nicholsPlot.Title.String = "Close Loop Bode";
            app.nicholsPlot.XLabel.String = "Frequency [rad/s]";
            app.nicholsPlot.XGrid = 'On';
            app.nicholsPlot.YGrid = 'On';
        end
        function [] = printPZmap(app,G)
            [p,z] = pzmap(G);
            
            if  ~isempty(p)
                plot(app.zpkMap,real(p),imag(p),'xr')
                hold(app.zpkMap,'on')
            end
            
            if ~isempty(z)
                plot(app.zpkMap,real(z),imag(z),'ob')
                hold(app.zpkMap,'on')
            end
            
            plot(app.zpkMap,[-50 50],[0 0],'k','LineWidth',0.5)
            hold(app.zpkMap,'on')
            plot(app.zpkMap,[0 0],[-50 50],'k','LineWidth',0.5)
            hold(app.zpkMap,'off')
            app.zpkMap.Title.String = "Pole - Zero Map";
            app.zpkMap.XLabel.String = "Real";
            app.zpkMap.YLabel.String = "Imaginary";
            
            %fill pz table
            app.zpkTable_p.Data = {};
            app.zpkTable_z.Data = {};
            if ~isempty(p)
                for i=1:length(p)
                    sys_poles(i,:) = {'pole' real(p(i)) imag(p(i))}; %sample data for each column
                end
                app.zpkTable_p.Data = [app.zpkTable_p.Data(:);sys_poles];
            end
            
            if ~isempty(z)
                for j=1:length(z)
                    sys_zeros(j,:) = {'zero' real(z(j)) imag(z(j))}; %sample data for each column
                end
                
                app.zpkTable_z.Data = [app.zpkTable_z.Data(:);sys_zeros];
            end
        end
        function [] = printCONTlist(app,type,reset)
            persistent i
            if isempty(i)
                i = 1;
            end
            
            if reset == 1
                i = 1;
            end
            
            if strcmp(type,'gain')
                app.ControllersList.Items(i) = {sprintf('#%d - gain',i)} ;
                i = i+1 ;
            end
            
        end
        function [] = updateGnic(app,G,des)
            Lnom = series(des.nom,G);
            t_color = des.col;
            plot(app.nicholsPlot,Lnom.phase,Lnom.mag,'k','LineWidth',1.2);
            hold(app.nicholsPlot,"on")
            for k=1:length(des.tpl)
                str = sprintf(' %g',des.tpl(k).frequency);
                tk = qfr(des.tpl(k).template(1),des.tpl(k).frequency);
                Ltpl = series(tk,G);
                x_text = Ltpl.phase;
                y_text = Ltpl.mag;
                plot(app.nicholsPlot,Ltpl.phase,Ltpl.mag,'marker','square',...
                    'markeredgecolor','k','markerfacecolor',t_color(k,:));
                text(app.nicholsPlot,x_text,y_text,str,'clipping','on') % single space added
            end
            hold(app.nicholsPlot,"off")
        end
        function [] = printHSbnd(app,des)
            sepcs_num = length(des.bnd);
            freq_num = length(des.bnd(1).w);
            
            for i = 1 : 1 : sepcs_num
                for j = 1 : 1 : freq_num
                    bounds_data = des.bnd(i).c;
                    print_bound = bounds_data{j,1};
                    plot(app.nicholsPlot,real(print_bound),imag(print_bound),'LineWidth',1.3)
                    hold(app.nicholsPlot,'on')
                end
            end
            
            ngrid(app.nicholsPlot)
            app.nicholsPlot.Title.String = "Nichols Plot";
            app.nicholsPlot.XLabel.String = "Phase [deg]";
            app.nicholsPlot.YLabel.String = "Mag [dB]";
            hold(app.nicholsPlot,"on")
            axis(app.nicholsPlot,'tight')
        end
        function [] = updateData(app,G,des)
            printCLbode(app,G,des);
            printHSbnd(app,des)
            updateGnic(app,G,des);
            printPZmap(app,G);
        end
        function [] = updateG(app,Controller)
            initial_cont = evalin("base",'G');
            TotalGain = 1;
            TotalLead = 1;
            TotalLag = 1;
            TotalPID = 1;
            TotalNotch = 1;
            TotalPZ = 1;
            
            for i=1:length(Controller.gain)
                TotalGain = TotalGain * Controller.gain(i);
                fprintf("%d",i)
            end
            
            for i=1:length(Controller.lead)
                TotalLead = TotalLead * Controller.lead(i);
            end
            
            for i=1:length(Controller.lag)
                TotalLag = TotalLag * Controller.lag(i);
            end
            
            for i=1:length(Controller.pid)
                TotalPID = TotalPID * Controller.pid(i);
            end
            
            for i=1:length(Controller.notch)
                TotalNotch = TotalNotch * Controller.notch(i);
            end
            
            for i=1:length(Controller.pz)
                TotalPZ = TotalPZ * Controller.pz(i);
            end
            
            app.G = initial_cont*TotalGain*TotalLead*TotalLag*TotalPID*TotalNotch*TotalPZ;
            
        end
        function [] = updateGainCont(app,G,des,gain,gain_flag)
            persistent i
            if isempty(i)
                i = 1;
            end
            if gain_flag == 1
                app.Controller.gain(i) = gain;
                updateG(app,app.Controller);
                updateData(app,app.G,des);
                printCONTlist(app,'gain',0);
                i = i+1;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %print the controller on text
            printGtext(app,app.G);
            
            %print pz map
            printPZmap(app,app.G);
            
            %print H-S bounds and the open loop on nichols chart
            printHSbnd(app,app.des);
            
            %plotting the open loop
            updateGnic(app,app.G,app.des)
            
            % define the controller
            app.Controller.gain(1) = 1;
            app.Controller.lead(1) = 1;
            app.Controller.lag(1) = 1;
            app.Controller.pid(1) = 1;
            app.Controller.notch(1) = 1;
            app.Controller.pz(1) = 1;
        end

        % Button pushed function: LoadPlant
        function LoadPlantButtonPushed(app, event)
            
        end

        % Button pushed function: ResetContoller
        function ResetContollerButtonPushed(app, event)
            % reset controller
            app.G = evalin("base",'G');
            
            printCONTlist(app,'',1)
            %reset graphs and tables
            app.nicholsPlot.cla
            app.zpkMap.cla
            app.ControllersList.Items = {};
            updateData(app,app.G,app.des)
            app.Controller.gain(:) = 1;
            app.Controller.lead(:) = 1;
            app.Controller.lag(:) = 1;
            app.Controller.pid(:) = 1;
            app.Controller.notch(:) = 1;
            app.Controller.pz(:) = 1;
            %update graphs with the reseted conrtoller
            %reset conrtollers counters
        end

        % Button pushed function: CLBode
        function CLBodeButtonPushed(app, event)
            app.nicholsPlot.cla
            printCLbode(app,app.G,app.des);
        end

        % Button pushed function: Nichols
        function NicholsButtonPushed(app, event)
            app.nicholsPlot.cla;
            printHSbnd(app,app.des);
            updateGnic(app,app.G,app.des);
        end

        % Button pushed function: LeadLag
        function LeadLagButtonPushed(app, event)
            app.DialogApp_lead = leadLagGUI(app,app.G,app.des);
        end

        % Button pushed function: Gain
        function GainButtonPushed(app, event)
            app.DialogApp_gain = gainGUI(app,app.G,app.des);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            closereq()
        end

        % Button pushed function: savenExit
        function savenExitButtonPushed(app, event)
            assignin("base","G",app.G)
            closereq()
        end

        % Button pushed function: OpenLoopBodeButton
        function OpenLoopBodeButtonPushed(app, event)
            printOLbode(app,app.G,app.des);
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            TotalGainCont = 1;
            cont_num = str2double(app.DelCont_num.Value);
            cont_str = app.ControllersList.Items(cont_num);
            app.ControllersList.Items(cont_num) = {sprintf('---')} ;
            str_4comp_gain = sprintf('#%d - gain',cont_num);
            str_4comp_lead = sprintf('#%d - lead',cont_num);
            str_4comp_lag = sprintf('#%d - lag',cont_num);
            str_4comp_pid = sprintf('#%d - pid',cont_num);
            str_4comp_notch = sprintf('#%d - notch',cont_num);
            str_4comp_zero = sprintf('#%d - zero',cont_num);
            if strcmp(string(cont_str),str_4comp_gain)
                app.G = app.G / app.gainCont(cont_num);
                app.gainCont(cont_num)=1;
                for i = 1 : length(app.gainCont)
                    TotalGainCont = TotalGainCont*app.gainCont(i);
                end
                app.G = app.G * TotalGainCont;
                printHSbnd(app,app.des);
                updateGnic(app,app.G,app.des);
                printPZmap(app,app.G);
            end
            %             case str_4comp_lead
            %                 fprintf("lead")
            %             case str_4comp_lag
            %                 fprintf("lag")
            %             case str_4comp_pid
            %                 fprintf("pid")
            %             case str_4comp_notch
            %                 fprintf("notch")
            %             case str_4comp_zero
            %                 fprintf("zero")
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            str_num = str2double(app.EditCont_num.Value);
            str_num = int8(str_num);
            type = convertCharsToStrings(app.ControllerList.Data(str_num,3));
            
            switch type
                case 'lead/lag'
                    waitfor(leadLagGUI(str_num))
                    % get the controller for w.s
                    leadlag_flag = evalin("base",'leadlag_flag');
                    %if the user pressed save&exit add the conrtoller to G, update
                    %controller list and update all graphs
                    if  leadlag_flag == 1
                        get_controller_str = sprintf("Clead_%d",str_num);
                        clead = evalin("base",get_controller_str);
                        % connect clead in series with G
                        app.G = app.G*clead;
                        % update all plots and maps
                        updateAll(app,app.G,app.des);
                    end
                case 'gain'
                    waitfor(gainGUI(str_num))
                    gain_flag = evalin("base",'gain_flag');
                    if gain_flag == 1
                        get_controller_str = sprintf("gain_%d",str_num);
                        gain = evalin("base",get_controller_str);
                        app.G = app.G*gain;
                        updateAll(app,app.G,app.des);
                    end
                case 'pid'
                    %add commands
                case 'notch'
                    %add commands
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LoopShapingGUI = uifigure('Visible', 'off');
            app.LoopShapingGUI.Name = 'UI Figure';
            app.ControllerPanel = uipanel(app.LoopShapingGUI);
            app.ControllerPanel.Title = 'Controller';
            app.LeadLag = uibutton(app.ControllerPanel, 'push');
            app.LeadLag.ButtonPushedFcn = createCallbackFcn(app, @LeadLagButtonPushed, true);
            app.LeadLag.Text = 'Lead/Lag';
            app.Notch = uibutton(app.ControllerPanel, 'push');
            app.Notch.Text = 'Notch/anti Notch';
            app.Gain = uibutton(app.ControllerPanel, 'push');
            app.Gain.ButtonPushedFcn = createCallbackFcn(app, @GainButtonPushed, true);
            app.Gain.Text = 'Gain';
            app.PID = uibutton(app.ControllerPanel, 'push');
            app.PID.Text = 'PID';
            app.AddPolesZeros = uibutton(app.ControllerPanel, 'push');
            app.AddPolesZeros.Text = 'Add Poles/Zeros';
            app.AutomaticDesigner = uibutton(app.ControllerPanel, 'push');
            app.AutomaticDesigner.Text = 'Automatic Designer';
            app.ResetContoller = uibutton(app.ControllerPanel, 'push');
            app.ResetContoller.ButtonPushedFcn = createCallbackFcn(app, @ResetContollerButtonPushed, true);
            app.ResetContoller.Text = 'Reset Contoller';
            app.ControllerText = uilabel(app.ControllerPanel);
            app.ControllerText.Text = 'Current Contoller:';
            app.CLBode = uibutton(app.ControllerPanel, 'push');
            app.CLBode.ButtonPushedFcn = createCallbackFcn(app, @CLBodeButtonPushed, true);
            app.CLBode.Text = 'Close Loop Bode';
            app.Nichols = uibutton(app.ControllerPanel, 'push');
            app.Nichols.ButtonPushedFcn = createCallbackFcn(app, @NicholsButtonPushed, true);
            app.Nichols.Text = 'Nichols';
            app.CurrentController = uilabel(app.ControllerPanel);
            app.CurrentController.Text = 'G(s) = 1';
            app.OpenLoopBodeButton = uibutton(app.ControllerPanel, 'push');
            app.OpenLoopBodeButton.ButtonPushedFcn = createCallbackFcn(app, @OpenLoopBodeButtonPushed, true);
            app.OpenLoopBodeButton.Text = 'Open Loop Bode';
            app.EditControllerEditFieldLabel = uilabel(app.ControllerPanel);
            app.EditControllerEditFieldLabel.Text = 'Edit Controller #:';
            app.EditCont_num = uieditfield(app.ControllerPanel, 'text');
            app.EditButton = uibutton(app.ControllerPanel, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.DeleteControllerEditFieldLabel = uilabel(app.ControllerPanel);
            app.DeleteControllerEditFieldLabel.Text = 'Delete Controller #:';
            app.DelCont_num = uieditfield(app.ControllerPanel, 'text');
            app.DeleteButton = uibutton(app.ControllerPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.ControllersList = uilistbox(app.ControllerPanel);
            app.ControllersList.Items = {};
            app.ControllersList.Value = {};
            app.NicholsBodePanel = uipanel(app.LoopShapingGUI);
            app.NicholsBodePanel.Title = 'Nichols / Bode';
            app.nicholsPlot = uiaxes(app.NicholsBodePanel);
            title(app.nicholsPlot, 'Title')
            xlabel(app.nicholsPlot, 'X')
            ylabel(app.nicholsPlot, 'Y')
            app.ControllerPolesandZerosPanel = uipanel(app.LoopShapingGUI);
            app.ControllerPolesandZerosPanel.Title = 'Controller Poles and Zeros';
            app.zpkMap = uiaxes(app.ControllerPolesandZerosPanel);
            title(app.zpkMap, 'Title')
            xlabel(app.zpkMap, 'X')
            ylabel(app.zpkMap, 'Y')
            app.zpkTable_p = uitable(app.ControllerPolesandZerosPanel);
            app.zpkTable_p.ColumnName = {'Type'; 'Real Value'; 'Imag Value'};
            app.zpkTable_p.RowName = {''; ''; ''};
            app.zpkTable_p.ColumnEditable = true;
            app.zpkTable_z = uitable(app.ControllerPolesandZerosPanel);
            app.zpkTable_z.ColumnName = {'Type'; 'Real Value'; 'Imag Value'};
            app.zpkTable_z.RowName = {''; ''; ''};
            app.zpkTable_z.ColumnEditable = true;
            app.OptionsPanel = uipanel(app.LoopShapingGUI);
            app.OptionsPanel.Title = 'Options';
            app.SaveController = uibutton(app.OptionsPanel, 'push');
            app.SaveController.Text = 'Save Controller To Workspace';
            app.LoadController = uibutton(app.OptionsPanel, 'push');
            app.LoadController.Text = 'Load Controller From Workspace';
            app.LoadPlant = uibutton(app.OptionsPanel, 'push');
            app.LoadPlant.ButtonPushedFcn = createCallbackFcn(app, @LoadPlantButtonPushed, true);
            app.LoadPlant.Text = 'Load Plant From Workspace';
            app.Help = uibutton(app.OptionsPanel, 'push');
            app.Help.Text = 'Help!';
            app.Discrete = uibutton(app.OptionsPanel, 'push');
            app.Discrete.Text = 'Discrete Design';
            app.savenExit = uibutton(app.OptionsPanel, 'push');
            app.savenExit.ButtonPushedFcn = createCallbackFcn(app, @savenExitButtonPushed, true);
            app.savenExit.Text = 'Save Controller to Workspace and Exit';
            app.CancelButton = uibutton(app.OptionsPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.LoopShapingGUILabel = uilabel(app.LoopShapingGUI);
            app.LoopShapingGUILabel.Text = 'Loop Shaping GUI';
            app.QFTLogo = uiimage(app.LoopShapingGUI);
            app.QFTLogo.ImageSource = 'logo.png';
            % Show the figure after all components are created
            app.LoopShapingGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = loopShapingGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LoopShapingGUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LoopShapingGUI)
        end
    end
end